classdef ES_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtremumSeekingUIFigure      matlab.ui.Figure
        UIAxesx1                     matlab.ui.control.UIAxes
        UIAxesx2                     matlab.ui.control.UIAxes
        RunButton                    matlab.ui.control.Button
        AlgorithmDropDownLabel       matlab.ui.control.Label
        AlgorithmDropDown            matlab.ui.control.DropDown
        PlantpathEditFieldLabel      matlab.ui.control.Label
        PlantpathEditField           matlab.ui.control.EditField
        AdditiveplantnoisePanel      matlab.ui.container.Panel
        zeta_aEditFieldLabel         matlab.ui.control.Label
        zeta_aEditField              matlab.ui.control.NumericEditField
        zeta_bEditFieldLabel         matlab.ui.control.Label
        zeta_bEditField              matlab.ui.control.NumericEditField
        ParametersPanel              matlab.ui.container.Panel
        c0EditFieldLabel             matlab.ui.control.Label
        c0EditField                  matlab.ui.control.NumericEditField
        c1EditFieldLabel             matlab.ui.control.Label
        c1EditField                  matlab.ui.control.NumericEditField
        c2EditFieldLabel             matlab.ui.control.Label
        c2EditField                  matlab.ui.control.NumericEditField
        epsilonLabel                 matlab.ui.control.Label
        epsilonEditField             matlab.ui.control.NumericEditField
        DEditFieldLabel              matlab.ui.control.Label
        DEditField                   matlab.ui.control.NumericEditField
        muEditFieldLabel             matlab.ui.control.Label
        muEditField                  matlab.ui.control.NumericEditField
        kEditFieldLabel              matlab.ui.control.Label
        kEditField                   matlab.ui.control.NumericEditField
        InitialvaluePanel            matlab.ui.container.Panel
        x1EditFieldLabel             matlab.ui.control.Label
        x1EditField                  matlab.ui.control.NumericEditField
        x2EditFieldLabel             matlab.ui.control.Label
        x2EditField                  matlab.ui.control.NumericEditField
        x1EditField_2Label           matlab.ui.control.Label
        x1dotEditField               matlab.ui.control.NumericEditField
        x2EditField_2Label           matlab.ui.control.Label
        x2dotEditField               matlab.ui.control.NumericEditField
        TimePanel                    matlab.ui.container.Panel
        t_initEditFieldLabel         matlab.ui.control.Label
        t_initEditField              matlab.ui.control.NumericEditField
        t_finEditFieldLabel          matlab.ui.control.Label
        t_finEditField               matlab.ui.control.NumericEditField
        stepEditFieldLabel           matlab.ui.control.Label
        stepEditField                matlab.ui.control.NumericEditField
        SaveButton                   matlab.ui.control.Button
        DimensionButtonGroup         matlab.ui.container.ButtonGroup
        Button                       matlab.ui.control.RadioButton
        Button_2                     matlab.ui.control.RadioButton
        GradientnoisePanel           matlab.ui.container.Panel
        NoisevalueminEditFieldLabel  matlab.ui.control.Label
        NoisevalueminEditField       matlab.ui.control.NumericEditField
        NoisetypeButtonGroup         matlab.ui.container.ButtonGroup
        UniformButton                matlab.ui.control.RadioButton
        GaussButton                  matlab.ui.control.RadioButton
        DiscreteButton               matlab.ui.control.RadioButton
        NoisevaluemaxEditFieldLabel  matlab.ui.control.Label
        NoisevaluemaxEditField       matlab.ui.control.NumericEditField
        alphaEditField_2Label        matlab.ui.control.Label
        alphaEditField               matlab.ui.control.NumericEditField
        PlotsPanel                   matlab.ui.container.Panel
        xCheckBox                    matlab.ui.control.CheckBox
        xCheckBox_2                  matlab.ui.control.CheckBox
        uCheckBox                    matlab.ui.control.CheckBox
        gradientestimateCheckBox     matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            
            par.a_zeta = app.zeta_aEditField.Value;
            par.b_zeta = app.zeta_bEditField.Value;
            par.k = app.kEditField.Value;
            par.mu = app.muEditField.Value;
            par.D = app.DEditField.Value;
            par.c0 = app.c0EditField.Value;
            par.c1 = app.c1EditField.Value;
            par.c2 = app.c2EditField.Value;
            par.alpha = app.alphaEditField.Value;
            par.a_xi = -0.5;
            par.b_xi = 0.5;
            par.epsilon = app.epsilonEditField.Value;
            par.algorithm = str2num(app.AlgorithmDropDown.Value);
            par.noise_value_min = app.NoisevalueminEditField.Value;
            par.noise_value_max = app.NoisevaluemaxEditField.Value;
            par.noise_type = 0;
            
            if app.UniformButton.Value
                par.noise_type = 1;
            end
            if app.GaussButton.Value
                par.noise_type = 2;
            end
            if app.DiscreteButton.Value
                par.noise_type = 3;
            end
            
            par.dim = 2;
            if app.Button.Value
                par.dim = 1;
            end
            
            if app.Button_2.Value
                x0 = [app.x1EditField.Value, app.x1dotEditField.Value, app.x2EditField.Value, app.x2dotEditField.Value];
            
            else 
                x0 = [app.x1EditField.Value, app.x1dotEditField.Value];
            
            end
                     
            ti = app.t_initEditField.Value; 
            tf = app.t_finEditField.Value; 
            tstep = app.stepEditField.Value;
            
            addpath(genpath(app.PlantpathEditField.Value));
            
            %options=odeset('OutputFcn',@odeprog,'Events',@odeabort);
            opt = odeset('AbsTol',1.0e-07,'RelTol',1.0e-07,'OutputFcn',@odeprog,'Events',@odeabort);
            
            if app.Button_2.Value
                [t,x] = ode15s( @plant, [ti:tstep:tf], x0 ,opt, par );
                table = [x(:,1), x(:,3)];
                table_dot = [x(:,2), x(:,4)];
            end
            if app.Button.Value
                [t,x] = ode15s( @plantdim1, [ti:tstep:tf], x0 ,opt, par );
                table = [x(:,1)];
                table_dot = [x(:,2)];
            end
           
            
            if (app.xCheckBox.Value)
                if app.Button_2.Value
                    figure();
                    subplot(1,2,1);
                    plot(t,table(:,1));
                    xlabel('t');
                    ylabel('x_1');
                    subplot(1,2,2);
                    plot(t,table(:,2));
                    xlabel('t');
                    ylabel('x_2');
                end
                if app.Button.Value
                    figure();
                    plot(t,table(:,1));
                    xlabel('t');
                    ylabel('x');
                end
            end
            
            if (app.xCheckBox_2.Value)
                if app.Button_2.Value
                    figure();
                    subplot(1,2,1);
                    plot(t,table_dot(:,1));
                    xlabel('t');
                    ylabel('x_1^.');
                    subplot(1,2,2);
                    plot(t,table_dot(:,2));
                    xlabel('t');
                    ylabel('x_2^.');
                end
                if app.Button.Value
                    figure();
                    plot(t,table_dot(:,1));
                    xlabel('t');
                    ylabel('x^.');
                end
            end
            
            if (app.uCheckBox.Value)
                if app.Button_2.Value
                    n = length(t);
                    u = zeros(n,2);
                    for i = 1:n
                        u(i,:) = control(t(i), x(i,:), par);
                    end
                    figure();
                    plot(t, u(:,1));
                    xlabel('t');
                    ylabel('u');
                    hold on;
                    plot(t, u(:,2));
                    hold off;
                    legend('u_1', 'u_2');
                    assignin('base', 'u', u);
                end
                if app.Button.Value
                    n = length(t);
                    u = zeros(n,1);
                    for i = 1:n
                        u(i,:) = control(t(i), x(i,:), par);
                    end
                    figure();
                    plot(t, u(:,1));
                    xlabel('t');
                    ylabel('u');
                    assignin('base', 'u', u);
                end
            end
            
            if (app.gradientestimateCheckBox.Value)
                if app.Button_2.Value
                    n = length(t);
                    grad = zeros(n,2);
                    if (par.algorithm == 1)
                        for i = 1:n
                            grad(i,:) = Gradient(t(i),x(i,:),par);
                        end
                    end
                    if (par.algorithm == 2)
                        for i = 1:n
                            grad(i,:) = Gradient_with_noise(t(i),x(i,:),par);
                        end
                    end
                    if (par.algorithm == 3)
                        for i = 1:n
                            grad(i,:) = Gradient_est_1(t(i),x(i,:),par);
                        end
                    end
                    if (par.algorithm == 4)
                        for i = 1:n
                            grad(i,:) = Gradient_est_2(t(i),x(i,:),par);
                        end
                    end
                    figure();
                    plot(t, grad(:,1));
                    xlabel('t');
                    ylabel('gradient estimate');
                    hold on;
                    plot(t, grad(:,2));
                    hold off;
                    legend('grad est_1', 'grad est_2');
                    assignin('base', 'grad', grad);
                end
                
                if app.Button.Value
                    n = length(t);
                    grad = zeros(n,1);
                    if (par.algorithm == 1)
                        for i = 1:n
                            grad(i,:) = Gradient(t(i),x(i,:),par);
                        end
                    end
                    if (par.algorithm == 2)
                        for i = 1:n
                            grad(i,:) = Gradient_with_noise(t(i),x(i,:),par);
                        end
                    end
                    if (par.algorithm == 3)
                        for i = 1:n
                            grad(i,:) = Gradient_est_1(t(i),x(i,:),par);
                        end
                    end
                    if (par.algorithm == 4)
                        for i = 1:n
                            grad(i,:) = Gradient_est_2(t(i),x(i,:),par);
                        end
                    end
                    figure();
                    plot(t, grad(:,1));
                    xlabel('t');
                    ylabel('gradient estimate');
                    assignin('base', 'grad', grad);
                end
            end
            
            assignin('base','t', t);
            assignin('base','x',x);
            assignin('base','table',table);
            assignin('base', 'table_dot', table_dot);
            assignin('base','par', par);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            time = clock;
            time_string = datestr(time, 'HH_MM_SS');
            FileName = strcat('results_', time_string, '.mat');
            evalin('base', char(strcat({'save '}, FileName)));
            
            %evalin('base', 'save(''app_results.mat'')');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExtremumSeekingUIFigure = uifigure('Visible', 'off');
            app.ExtremumSeekingUIFigure.Name = 'Extremum Seeking';
            app.UIAxesx1 = uiaxes(app.ExtremumSeekingUIFigure);
            title(app.UIAxesx1, 'x1')
            xlabel(app.UIAxesx1, 't')
            ylabel(app.UIAxesx1, 'x1')
            app.UIAxesx1.Visible = 'off';
            app.UIAxesx1.Tag = 'x1';
            app.UIAxesx2 = uiaxes(app.ExtremumSeekingUIFigure);
            title(app.UIAxesx2, 'x2')
            xlabel(app.UIAxesx2, 't')
            ylabel(app.UIAxesx2, 'x2')
            app.UIAxesx2.Visible = 'off';
            app.UIAxesx2.Tag = 'x2';
            app.RunButton = uibutton(app.ExtremumSeekingUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AlgorithmDropDownLabel = uilabel(app.ExtremumSeekingUIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.ExtremumSeekingUIFigure);
            app.AlgorithmDropDown.Items = {'Gradient', 'Gradient with noise', 'Gradient estimate with one measurment', 'Gradient estimate with two measurments'};
            app.AlgorithmDropDown.ItemsData = {'1', '2', '3', '4'};
            app.AlgorithmDropDown.Value = '2';
            app.PlantpathEditFieldLabel = uilabel(app.ExtremumSeekingUIFigure);
            app.PlantpathEditFieldLabel.Text = 'Plant path:';
            app.PlantpathEditField = uieditfield(app.ExtremumSeekingUIFigure, 'text');
            app.AdditiveplantnoisePanel = uipanel(app.ExtremumSeekingUIFigure);
            app.AdditiveplantnoisePanel.Title = 'Additive plant noise';
            app.zeta_aEditFieldLabel = uilabel(app.AdditiveplantnoisePanel);
            app.zeta_aEditFieldLabel.Text = 'zeta_a';
            app.zeta_aEditField = uieditfield(app.AdditiveplantnoisePanel, 'numeric');
            app.zeta_aEditField.Tag = 'zeta_a';
            app.zeta_aEditField.Value = -1e-07;
            app.zeta_bEditFieldLabel = uilabel(app.AdditiveplantnoisePanel);
            app.zeta_bEditFieldLabel.Text = 'zeta_b';
            app.zeta_bEditField = uieditfield(app.AdditiveplantnoisePanel, 'numeric');
            app.zeta_bEditField.Tag = 'zeta_b';
            app.zeta_bEditField.Value = 1e-07;
            app.ParametersPanel = uipanel(app.ExtremumSeekingUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.c0EditFieldLabel = uilabel(app.ParametersPanel);
            app.c0EditFieldLabel.Text = 'c0';
            app.c0EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.c0EditField.Tag = 'c0';
            app.c0EditField.Value = 1;
            app.c1EditFieldLabel = uilabel(app.ParametersPanel);
            app.c1EditFieldLabel.Text = 'c1';
            app.c1EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.c1EditField.Tag = 'c1';
            app.c1EditField.Value = 1;
            app.c2EditFieldLabel = uilabel(app.ParametersPanel);
            app.c2EditFieldLabel.Text = 'c2';
            app.c2EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.c2EditField.Tag = 'c2';
            app.c2EditField.Value = 1;
            app.epsilonLabel = uilabel(app.ParametersPanel);
            app.epsilonLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.epsilonEditField.Tag = 'epsilon';
            app.epsilonEditField.Value = 0.01;
            app.DEditFieldLabel = uilabel(app.ParametersPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DEditField.Tag = 'D';
            app.DEditField.Value = 0.5;
            app.muEditFieldLabel = uilabel(app.ParametersPanel);
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.muEditField.Tag = 'mu';
            app.muEditField.Value = 0.03;
            app.kEditFieldLabel = uilabel(app.ParametersPanel);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.kEditField.Tag = 'k';
            app.kEditField.Value = 300;
            app.InitialvaluePanel = uipanel(app.ExtremumSeekingUIFigure);
            app.InitialvaluePanel.Title = 'Initial value';
            app.x1EditFieldLabel = uilabel(app.InitialvaluePanel);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.InitialvaluePanel, 'numeric');
            app.x1EditField.Value = 1.3;
            app.x2EditFieldLabel = uilabel(app.InitialvaluePanel);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.InitialvaluePanel, 'numeric');
            app.x2EditField.Value = -0.1;
            app.x1EditField_2Label = uilabel(app.InitialvaluePanel);
            app.x1EditField_2Label.Text = 'x1''';
            app.x1dotEditField = uieditfield(app.InitialvaluePanel, 'numeric');
            app.x2EditField_2Label = uilabel(app.InitialvaluePanel);
            app.x2EditField_2Label.Text = 'x2''';
            app.x2dotEditField = uieditfield(app.InitialvaluePanel, 'numeric');
            app.TimePanel = uipanel(app.ExtremumSeekingUIFigure);
            app.TimePanel.Title = 'Time';
            app.t_initEditFieldLabel = uilabel(app.TimePanel);
            app.t_initEditFieldLabel.Text = 't_init';
            app.t_initEditField = uieditfield(app.TimePanel, 'numeric');
            app.t_initEditField.Tag = 't_init';
            app.t_finEditFieldLabel = uilabel(app.TimePanel);
            app.t_finEditFieldLabel.Text = 't_fin';
            app.t_finEditField = uieditfield(app.TimePanel, 'numeric');
            app.t_finEditField.Tag = 't_fin';
            app.t_finEditField.Value = 100;
            app.stepEditFieldLabel = uilabel(app.TimePanel);
            app.stepEditFieldLabel.Text = 'step';
            app.stepEditField = uieditfield(app.TimePanel, 'numeric');
            app.stepEditField.Tag = 'step';
            app.stepEditField.Value = 0.001;
            app.SaveButton = uibutton(app.ExtremumSeekingUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DimensionButtonGroup = uibuttongroup(app.ExtremumSeekingUIFigure);
            app.DimensionButtonGroup.Title = 'Dimension';
            app.Button = uiradiobutton(app.DimensionButtonGroup);
            app.Button.Text = '1';
            app.Button_2 = uiradiobutton(app.DimensionButtonGroup);
            app.Button_2.Text = '2';
            app.Button_2.Value = true;
            app.GradientnoisePanel = uipanel(app.ExtremumSeekingUIFigure);
            app.GradientnoisePanel.Title = 'Gradient noise';
            app.NoisevalueminEditFieldLabel = uilabel(app.GradientnoisePanel);
            app.NoisevalueminEditFieldLabel.Text = 'Noise value min';
            app.NoisevalueminEditField = uieditfield(app.GradientnoisePanel, 'numeric');
            app.NoisevalueminEditField.Value = -5e-07;
            app.NoisetypeButtonGroup = uibuttongroup(app.GradientnoisePanel);
            app.NoisetypeButtonGroup.Title = 'Noise type';
            app.UniformButton = uiradiobutton(app.NoisetypeButtonGroup);
            app.UniformButton.Text = 'Uniform';
            app.GaussButton = uiradiobutton(app.NoisetypeButtonGroup);
            app.GaussButton.Text = 'Gauss';
            app.DiscreteButton = uiradiobutton(app.NoisetypeButtonGroup);
            app.DiscreteButton.Text = 'Discrete';
            app.DiscreteButton.Value = true;
            app.NoisevaluemaxEditFieldLabel = uilabel(app.GradientnoisePanel);
            app.NoisevaluemaxEditFieldLabel.Text = 'Noise value max';
            app.NoisevaluemaxEditField = uieditfield(app.GradientnoisePanel, 'numeric');
            app.NoisevaluemaxEditField.Value = 5e-07;
            app.alphaEditField_2Label = uilabel(app.GradientnoisePanel);
            app.alphaEditField_2Label.Text = 'alpha';
            app.alphaEditField = uieditfield(app.GradientnoisePanel, 'numeric');
            app.alphaEditField.Value = 1e-06;
            app.PlotsPanel = uipanel(app.ExtremumSeekingUIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.xCheckBox = uicheckbox(app.PlotsPanel);
            app.xCheckBox.Text = 'x';
            app.xCheckBox.Value = true;
            app.xCheckBox_2 = uicheckbox(app.PlotsPanel);
            app.xCheckBox_2.Text = 'x''';
            app.uCheckBox = uicheckbox(app.PlotsPanel);
            app.uCheckBox.Text = 'u';
            app.gradientestimateCheckBox = uicheckbox(app.PlotsPanel);
            app.gradientestimateCheckBox.Text = 'gradient estimate';
            % Show the figure after all components are created
            app.ExtremumSeekingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ES_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExtremumSeekingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtremumSeekingUIFigure)
        end
    end
end